classdef NT_Visualizer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                       matlab.ui.Figure
		SortDatasetsbyMTFTypeListBox   matlab.ui.control.ListBox
		SortDatasetsbyMTFTypeListBoxLabel  matlab.ui.control.Label
		DatasetsListBox                matlab.ui.control.ListBox
		DatasetsListBoxLabel           matlab.ui.control.Label
		CFLabel_2                      matlab.ui.control.Label
		ModelListBox_2                 matlab.ui.control.ListBox
		ModelListBox_2Label            matlab.ui.control.Label
		ModelPopulationResponsesLabel  matlab.ui.control.Label
		SingleCFResponsesLabel         matlab.ui.control.Label
		CFLabel                        matlab.ui.control.Label
		FreqLinekHzSlider              matlab.ui.control.Slider
		FreqLinekHzLabel               matlab.ui.control.Label
		BELabel                        matlab.ui.control.Label
		BSLabel                        matlab.ui.control.Label
		NoteLabel                      matlab.ui.control.Label
		NaturalTimbreStimulusSFIEPredictionLabel  matlab.ui.control.Label
		F0HzSlider                     matlab.ui.control.Slider
		StimulusF0Label                matlab.ui.control.Label
		UIAxes5                        matlab.ui.control.UIAxes
		UIAxes4                        matlab.ui.control.UIAxes
		UIAxes3                        matlab.ui.control.UIAxes
		UIAxes2_2                      matlab.ui.control.UIAxes
		UIAxes2                        matlab.ui.control.UIAxes
		UIAxes                         matlab.ui.control.UIAxes
	end

	
	properties (Access = public)
		files;
		order;
		pitch; % Description
		stims;
		Fs;
		ordered_names;
		SFIE_1;
		SFIE_2;
		CF_range_1; CF_range_2; CF_range;
		ERB;
		hLine; hLine2; hLine3; hLine4; hLine5; hLine6; hLine7; hLine8; hLine9;
		plot1; plot2; plot3; plot4; plot5; plot6; plot7, plot8, plot9;
		energy;
		band;
		data;
		data_ind2;
	end
	
	methods (Access = private)
		
		function loadData(app)
			value = app.ModelListBox_2.Value;

			% Load in new
			if strcmp(value, 'Cat SFIE')
				load('Model_NT_cat.mat', "AN", "SFIE_BS", "SFIE_BE", "CFs", "RMS", "erb");
				app.SFIE_1 = SFIE_BE;
				app.SFIE_2 = SFIE_BS;
				app.energy = RMS;
				app.CF_range_1 = CFs;
				app.CF_range = CFs;
				app.CF_range_2 = CFs;
				app.ERB = erb;
			elseif strcmp(value, 'Cat Lat Inh')
				load('Model_NT_cat_latinh.mat', "latinh");
				app.SFIE_1 = latinh.BE;
				app.SFIE_2 = latinh.BS;
				app.CF_range_1 = latinh.BE_CFs;
				app.CF_range_2 = latinh.BS_CFs;
			elseif strcmp(value, 'Human SFIE')
				load('Model_NT_human.mat', "AN", "SFIE_BS", "SFIE_BE", "CFs", "RMS", "erb");
				app.SFIE_1 = SFIE_BE;
				app.SFIE_2 = SFIE_BS;
				app.energy = RMS;
				app.CF_range_1 = CFs;
				app.CF_range_2 = CFs;
				app.ERB = erb;
			end

		end
		
		function plotData(app, index)

			% Plot on top of energy 
			delete(app.plot7)
			delete(app.plot9)
			delete(app.plot8)

			max_rate = max(app.plot6.YData);
			ratio = max_rate/max(app.data.rates(index,:));
			scaled_rate = app.data.rates(index,:) .* ratio;
			scaled_error = (app.data.rates(index,:)./sqrt(30) .* ratio);
			app.plot7 = errorbar(app.UIAxes5, 1:size(app.data.rates, 2), scaled_rate, scaled_error, 'Color',"#EDB120", 'LineWidth',1.5);

			% Plot
			MTFtype = app.SortDatasetsbyMTFTypeListBox.ValueIndex;
			if MTFtype == 2
				max_rate = max(app.plot4.YData);
				ratio = max_rate/max(app.data.rates(index,:));
				scaled_rate = app.data.rates(index,:) .* ratio;
				scaled_error = (app.data.rates(index,:) .* ratio)./sqrt(30);
				app.plot8 = errorbar(app.UIAxes4, 1:size(app.data.rates, 2), scaled_rate, scaled_error, 'Color',"#EDB120", 'LineWidth',1.5);
			elseif MTFtype == 1
				max_rate = max(app.plot5.YData);
				ratio = max_rate/max(app.data.rates(index,:));
				scaled_rate = app.data.rates(index,:) .* ratio;
				scaled_error = (app.data.rates(index,:) .* ratio)./sqrt(30);
				app.plot9 = errorbar(app.UIAxes3, 1:size(app.data.rates, 2), scaled_rate, scaled_error, 'Color',"#EDB120", 'LineWidth',1.5);
			end
		end
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			
			% Load in spreadsheets and all stimuli
			if ismac
				fpath = '/Users/jfritzinger/Library/CloudStorage/Box-Box/03 - Physiology/07 - Natural Timbre/Cut Waveforms/';
			else
				fpath = 'C:\Users\jfritzinger\Box\02 - Physiology\07 - Natural Timbre\Cut Waveforms\';
			end
			target = 'Bassoon';
			listing = dir(fullfile(fpath, '*.wav'));
			target_WAV = arrayfun(@(n) contains(listing(n).name, target), 1:numel(listing), 'UniformOutput', false);
			wav_nums =  find(cell2mat(target_WAV));

			if ismac
				tuning_dir = '/Users/jfritzinger/Library/CloudStorage/Box-Box/03 - Physiology/07 - Natural Timbre/Cut Waveforms/';
			else
				tuning_dir = 'C:\Users\jfritzinger\Box\03 - Physiology\07 - Natural Timbre\Cut Waveforms\';
			end
			tuning = readtable([tuning_dir 'Tuning.xlsx']);
			d = dir(fullfile(fpath,'*.wav'));
			all_files = sort({d.name});
			nfiles = length(wav_nums);
			for i = 1:nfiles
				app.files{1,i} = all_files{wav_nums(i)};
			end

			% Sort by frequency of pitch
			index = [];
			note_names = extractBetween(app.files, 'ff.','.');
			for ii = 1:nfiles % Find index of each note in tuning spreadsheet
				index(ii) = find(strcmp(note_names(ii), tuning.Note));
			end
			pitch_order = tuning.Frequency(index); % Get freqs of each note
			[~, app.order] = sort(pitch_order); % Sort freqs
			app.pitch = pitch_order(app.order);
			app.ordered_names = note_names(app.order);
			for ii = 1:nfiles
				[app.stims{ii}, app.Fs] = audioread([fpath app.files{app.order(ii)}]);
			end			
			loadData(app)

			%% Plot

			% Plot stimulus
			stim = app.stims{1};
			F0 = app.pitch(1);
			y2 = fft(stim);
			m = abs(y2);
			mdB = 20*log10(m);
			f = (0:length(y2)-1)*app.Fs/length(y2);
			mdB(mdB<0) = 0;
			f(f>app.Fs/2) = [];
			mdB = mdB(1:length(f))';
			hold(app.UIAxes, 'on')
			app.plot1 = plot(app.UIAxes, f, mdB,  'k', 'LineWidth', 2);
			xticks(app.UIAxes, [0.05 0.1 0.2 0.5 1 2 5 10]*1000)
			xticklabels(app.UIAxes, [0.05 0.1 0.2 0.5 1 2 5 10])
			grid(app.UIAxes, "on")
			xlim(app.UIAxes, [125 10000])

			% Plot SFIE BS
			response = app.SFIE_2(1,:);
			hold(app.UIAxes2, 'on')
			app.plot2 = plot(app.UIAxes2, app.CF_range_2, response, 'k');
			xticks(app.UIAxes2, [0.05 0.1 0.2 0.5 1 2 5 10]*1000)
			xticklabels(app.UIAxes2, [0.05 0.1 0.2 0.5 1 2 5 10])
			grid(app.UIAxes2, "on")
			xlim(app.UIAxes2, [125 10000])
			ylim(app.UIAxes2, [0 50])

			% Plot SFIE BE
			response = app.SFIE_1(1,:);
			hold(app.UIAxes2_2, 'on')
			app.plot3 = plot(app.UIAxes2_2, app.CF_range_1, response, 'k');
			xticks(app.UIAxes2_2, [0.05 0.1 0.2 0.5 1 2 5 10]*1000)
			xticklabels(app.UIAxes2_2, [0.05 0.1 0.2 0.5 1 2 5 10])
			grid(app.UIAxes2_2, "on")
			xlim(app.UIAxes2_2, [125 10000])
			ylim(app.UIAxes2_2, [0 80])

			%% Plot rate responses 
			% SFIE BS 
			response = app.SFIE_2(:,1);
			num_stim = size(app.SFIE_2, 1);
			hold(app.UIAxes4, "on")
			app.plot4 = plot(app.UIAxes4, 1:num_stim, response, 'k-o', 'MarkerSize',5);

			% SFIE BE
			response = app.SFIE_1(:,1);
			hold(app.UIAxes3, "on")
			app.plot5 = plot(app.UIAxes3, 1:num_stim, response, 'k-o', 'MarkerSize',5);

			% Energy
			response = app.energy(:,1);
			hold(app.UIAxes5, "on")
			app.plot6 = plot(app.UIAxes5, 1:num_stim, response, 'k-o', 'MarkerSize',5);

			linkaxes([app.UIAxes app.UIAxes2, app.UIAxes2_2],'x');

			%% Load in data 
			load("Data_NT_Matrix.mat", "data_all")
			app.data = data_all;

			MTFtypes = app.SortDatasetsbyMTFTypeListBox.ValueIndex;
			if MTFtypes == 1
				data_ind = strcmp(app.data.MTF_shapes, 'BE');
			elseif MTFtypes == 2
				data_ind = strcmp(app.data.MTF_shapes, 'BS');
			end
			data_ind = find(data_ind);
			for ii = 1:length(data_ind)
				sessions_interest{ii} = sprintf('CF=%0.fHz, %s', app.data.CFs(data_ind(ii)), app.data.MTF_shapes{data_ind(ii)});
			end
			app.DatasetsListBox.Items = sessions_interest;
			app.data_ind2 = data_ind;

		end

		% Value changed function: F0HzSlider
		function F0HzSliderValueChanged(app, event)
			value = app.F0HzSlider.Value;
			value = round(value);
			delete(app.plot1)
			delete(app.plot2)
			delete(app.plot3)

			% Calculate spectra
			stim = app.stims{value};
			F0 = app.pitch(value);
			y2 = fft(stim);
			m = abs(y2);
			mdB = 20*log10(m);
			f = (0:length(y2)-1)*app.Fs/length(y2);
			mdB(mdB<0) = 0;
			f(f>app.Fs/2) = [];
			mdB = mdB(1:length(f))';

			% Plot
			hold(app.UIAxes, 'on')
			app.plot1 = plot(app.UIAxes, f, mdB, 'LineWidth', 1.5, 'Color','k');
			app.NoteLabel.Text = sprintf('Note: %s, %0.f Hz', app.ordered_names{value},F0);

			% Plot SFIE response
			response = app.SFIE_2(value,:);
			hold(app.UIAxes2, 'on')
			app.plot2 = plot(app.UIAxes2, app.CF_range_2, response, 'k');
			response = app.SFIE_1(value,:);
			hold(app.UIAxes2_2, 'on')
			app.plot3 = plot(app.UIAxes2_2, app.CF_range_1, response, 'k');

			% Change line
			delete(app.hLine4)
			delete(app.hLine5)
			delete(app.hLine6)
			app.hLine4 = xline(app.UIAxes4, value, 'b', 'LineWidth',2);
			app.hLine5 = xline(app.UIAxes3, value, 'b', 'LineWidth',2);
			app.hLine6 = xline(app.UIAxes5, value, 'b', 'LineWidth',2);

			delete(app.hLine7)
			delete(app.hLine8)
			delete(app.hLine9)
			octaves = [F0/4 F0/2 F0*2 F0*4];
			app.pitch(value)
			for i = 1:4
				ind = find(round(octaves(i))==round(app.pitch));
				if isempty(ind)
					index(i)= 0;
				else
					index(i) = ind;
				end
			end
			%app.hLine7 = xline(app.UIAxes4, index, 'LineWidth',2, 'Color','#A5A5FF');
			%app.hLine8 = xline(app.UIAxes3, index, 'LineWidth',2, 'Color','#A5A5FF');
			%app.hLine9 = xline(app.UIAxes5, index, 'LineWidth',2, 'Color','#A5A5FF');

		end

		% Callback function
		function CharacteristicFrequencyEditFieldValueChanged(app, event)
		
		end

		% Value changing function: F0HzSlider
		function F0HzSliderValueChanging(app, event)
			changingValue = event.Value;
			value = round(changingValue);
			delete(app.plot1)
			delete(app.plot2)
			delete(app.plot3)

			% Calculate spectra
			stim = app.stims{value};
			F0 = app.pitch(value);
			y2 = fft(stim);
			m = abs(y2);
			mdB = 20*log10(m);
			f = (0:length(y2)-1)*app.Fs/length(y2);
			mdB(mdB<0) = 0;
			f(f>app.Fs/2) = [];
			mdB = mdB(1:length(f))';

			% Plot
			hold(app.UIAxes, 'on')
			app.plot1 = plot(app.UIAxes, f, mdB, 'LineWidth', 1.5, 'Color','k');
			app.NoteLabel.Text = sprintf('Note: %s, %0.f Hz', app.ordered_names{value},F0);

			% Plot SFIE response
			response = app.SFIE_2(value,:);
			hold(app.UIAxes2, "on")
			app.plot2 = plot(app.UIAxes2, app.CF_range_2, response, 'k');

			response = app.SFIE_1(value,:);
			hold(app.UIAxes2_2, "on")
			app.plot3 = plot(app.UIAxes2_2, app.CF_range_1, response, 'k');

			% Change line
			delete(app.hLine4)
			delete(app.hLine5)
			delete(app.hLine6)
			app.hLine4 = xline(app.UIAxes4, value, 'b', 'LineWidth',2);
			app.hLine5 = xline(app.UIAxes3, value, 'b', 'LineWidth',2);
			app.hLine6 = xline(app.UIAxes5, value, 'b', 'LineWidth',2);
			
			delete(app.hLine7)
			delete(app.hLine8)
			delete(app.hLine9)
			octaves = [F0/4 F0/2 F0*2 F0*4];
			app.pitch(value)
			for i = 1:4
				ind = find(round(octaves(i))==round(app.pitch));
				if isempty(ind)
					index(i)= 0;
				else
					index(i) = ind;
				end
			end
			%app.hLine7 = xline(app.UIAxes4, index, 'LineWidth',2, 'Color','#A5A5FF');
			%app.hLine8 = xline(app.UIAxes3, index, 'LineWidth',2, 'Color','#A5A5FF');
			%app.hLine9 = xline(app.UIAxes5, index, 'LineWidth',2, 'Color','#A5A5FF');


		end

		% Value changing function: FreqLinekHzSlider
		function FreqLinekHzSliderValueChanging(app, event)
			changingValue = round(event.Value);
			actual_CF = app.CF_range(changingValue);
			
			% Plot vertical red line 
			delete(app.hLine)
			delete(app.hLine2)
			delete(app.hLine3)
			app.hLine = xline(app.UIAxes, actual_CF, ...
				'r', 'LineWidth',2);
			app.hLine2 = xline(app.UIAxes2, actual_CF, ...
				'r', 'LineWidth',2);
			app.hLine3 = xline(app.UIAxes2_2, actual_CF, ...
				'r', 'LineWidth',2);

			% Plot grey band for ERB 
			CF_erb = app.ERB(changingValue);
			b = -(CF_erb/actual_CF);
			c = -1;
			ratio = (-b + sqrt(b^2 - 4*c))/(2);
			lo_freq = actual_CF / ratio;
			hi_freq = actual_CF * ratio;
			delete(app.band)
			app.band = area(app.UIAxes, [lo_freq hi_freq], [60 60], 'FaceAlpha', 0.3, 'FaceColor',[0.7 0.7 0.7]);

			% Update CF label
			app.CFLabel.Text = sprintf('CF = %.0f Hz', actual_CF);
			app.CFLabel_2.Text = sprintf('CF = %.0f Hz', actual_CF);

			% Delete plots 
			delete(app.plot4)
			delete(app.plot5)
			delete(app.plot6)

			% Update rate-stimulus plots
			num_stim = size(app.SFIE_2, 1);

			[~, nearest_ind] = min(abs(actual_CF-app.CF_range_2));
			response = app.SFIE_2(:,nearest_ind(1));
			app.plot4 = plot(app.UIAxes4, 1:num_stim, response, 'k-o', 'MarkerSize',5); % SFIE BS 		
			
			[~, nearest_ind] = min(abs(actual_CF-app.CF_range_1));
			response = app.SFIE_1(:,nearest_ind(1));
			app.plot5 = plot(app.UIAxes3, 1:num_stim, response, 'k-o', 'MarkerSize',5); % SFIE BE
			
			response = app.energy(:,changingValue);
			app.plot6 = plot(app.UIAxes5, 1:num_stim, response, 'k-o', 'MarkerSize',5); % Energy

			% Replot data scaled
			index = app.DatasetsListBox.ValueIndex;
			%plotData(app, index)
		end

		% Value changed function: ModelListBox_2
		function ModelListBox_2ValueChanged(app, event)
			loadData(app)
			F0HzSliderValueChanged(app, [])
			
			% Replot energy/rates 
			changingValue = round(app.FreqLinekHzSlider.Value);
			actual_CF = app.CF_range(changingValue);

			delete(app.hLine)
			delete(app.hLine2)
			delete(app.hLine3)
			app.hLine = xline(app.UIAxes, actual_CF, ...
				'r', 'LineWidth',2);
			app.hLine2 = xline(app.UIAxes2, actual_CF, ...
				'r', 'LineWidth',2);
			app.hLine3 = xline(app.UIAxes2_2, actual_CF, ...
				'r', 'LineWidth',2);

			% Update CF label
			app.CFLabel.Text = sprintf('CF = %.0f Hz', actual_CF);
			app.CFLabel_2.Text = sprintf('CF = %.0f Hz', actual_CF);
			
			delete(app.plot4)
			delete(app.plot5)
			delete(app.plot6)

			% SFIE BS 
			[~, nearest_ind] = min(abs(actual_CF-app.CF_range_2));
			response = app.SFIE_2(:,nearest_ind);
			num_stim = size(app.SFIE_2, 1);
			app.plot4 = plot(app.UIAxes4, 1:num_stim, response, 'k-o', 'MarkerSize',5);

			% SFIE BE
			[~, nearest_ind] = min(abs(actual_CF-app.CF_range_1));
			response = app.SFIE_1(:,nearest_ind);
			app.plot5 = plot(app.UIAxes3, 1:num_stim, response, 'k-o', 'MarkerSize',5);

			response = app.energy(:,changingValue);
			app.plot6 = plot(app.UIAxes5, 1:num_stim, response, 'k-o', 'MarkerSize',5);

			CF_erb = app.ERB(changingValue);
			CF = app.CF_range_1(changingValue);
			b = -(CF_erb/CF);
			c = -1;
			ratio = (-b + sqrt(b^2 - 4*c))/(2);
			lo_freq = CF / ratio;
			hi_freq = CF * ratio;
			delete(app.band)
			app.band = area(app.UIAxes, [lo_freq hi_freq], [60 60], 'FaceAlpha', 0.3, 'FaceColor',[0.7 0.7 0.7]);

			% Replot data scaled
			index = app.DatasetsListBox.ValueIndex;
			%plotData(app, index)

		end

		% Callback function: not associated with a component
		function ModelListBoxValueChanged(app, event)
			loadData(app)
		end

		% Value changed function: SortDatasetsbyMTFTypeListBox
		function SortDatasetsbyMTFTypeListBoxValueChanged(app, event)
			MTFtypes = app.SortDatasetsbyMTFTypeListBox.ValueIndex;
			if MTFtypes == 1
				data_ind = strcmp(app.data.MTF_shapes, 'BE');
			elseif MTFtypes == 2
				data_ind = strcmp(app.data.MTF_shapes, 'BS');
			elseif MTFtypes == 3
				data_ind = contains(app.data.MTF_shapes,'H');
			elseif MTFtypes == 4
				data_ind = strcmp(app.data.MTF_shapes, 'F');
			end
			data_ind = find(data_ind);
			for ii = 1:length(data_ind)
				sessions_interest{ii} = sprintf('CF=%0.fHz, %s', app.data.CFs(data_ind(ii)), app.data.MTF_shapes{data_ind(ii)});
			end
			app.DatasetsListBox.Items = sessions_interest;
			app.data_ind2 = data_ind;
			
		end

		% Callback function: not associated with a component
		function DatasetsListBoxValueChanged(app, event)

		end

		% Clicked callback: DatasetsListBox
		function DatasetsListBoxClicked(app, event)
			item = event.InteractionInformation.Item;
			index = app.data_ind2(item);
			plotData(app, index)

		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIAxes = uiaxes(app.UIFigure);
			title(app.UIAxes, 'Bassoon Stimulus')
			xlabel(app.UIAxes, 'Frequency (kHz)')
			ylabel(app.UIAxes, 'Amplitude ')
			zlabel(app.UIAxes, 'Z')
			app.UIAxes.XLim = [50 10000];
			app.UIAxes.XTick = [200 500 1000 2000 5000 10000];
			app.UIAxes.XScale = 'log';
			app.UIAxes2 = uiaxes(app.UIFigure);
			ylabel(app.UIAxes2, 'Spike Rate (sp/s)')
			zlabel(app.UIAxes2, 'Z')
			app.UIAxes2.XLim = [50 10000];
			app.UIAxes2.XTick = [200 500 1000 2000 5000 10000];
			app.UIAxes2.XScale = 'log';
			app.UIAxes2_2 = uiaxes(app.UIFigure);
			xlabel(app.UIAxes2_2, 'CF (kHz)')
			ylabel(app.UIAxes2_2, 'Spike Rate (sp/s)')
			zlabel(app.UIAxes2_2, 'Z')
			app.UIAxes2_2.XLim = [50 10000];
			app.UIAxes2_2.YLim = [0 80];
			app.UIAxes2_2.XTick = [200 500 1000 2000 5000 10000];
			app.UIAxes2_2.XScale = 'log';
			app.UIAxes3 = uiaxes(app.UIFigure);
			xlabel(app.UIAxes3, 'F0 (Hz)')
			ylabel(app.UIAxes3, 'Rate (sp/s)')
			zlabel(app.UIAxes3, 'Z')
			app.UIAxes3.XLim = [1 40];
			app.UIAxes3.YLim = [0 80];
			app.UIAxes3.XTickLabel = {'73'; '98'; '131'; '175'; '233'; '330'; '440'; '587'};
			app.UIAxes4 = uiaxes(app.UIFigure);
			ylabel(app.UIAxes4, 'Rate (sp/s)')
			zlabel(app.UIAxes4, 'Z')
			app.UIAxes4.XLim = [1 40];
			app.UIAxes4.YLim = [0 50];
			app.UIAxes4.XTickLabel = {'73'; '98'; '131'; '175'; '233'; '330'; '440'; '587'};
			app.UIAxes5 = uiaxes(app.UIFigure);
			title(app.UIAxes5, 'Energy')
			xlabel(app.UIAxes5, 'F0 (Hz)')
			ylabel(app.UIAxes5, 'RMS')
			zlabel(app.UIAxes5, 'Z')
			app.UIAxes5.XLim = [1 40];
			app.UIAxes5.XTickLabel = {'73'; '98'; '131'; '175'; '233'; '330'; '440'; '587'};
			app.StimulusF0Label = uilabel(app.UIFigure);
			app.StimulusF0Label.Text = {'F0 '; '(Hz)'};
			app.F0HzSlider = uislider(app.UIFigure);
			app.F0HzSlider.Limits = [1 40];
			app.F0HzSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
			app.F0HzSlider.MajorTickLabels = {'0', '73', '98', '131', '175', '233', '330', '440', '587'};
			app.F0HzSlider.ValueChangedFcn = createCallbackFcn(app, @F0HzSliderValueChanged, true);
			app.F0HzSlider.ValueChangingFcn = createCallbackFcn(app, @F0HzSliderValueChanging, true);
			app.F0HzSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
			app.F0HzSlider.Value = 1;
			app.NaturalTimbreStimulusSFIEPredictionLabel = uilabel(app.UIFigure);
			app.NaturalTimbreStimulusSFIEPredictionLabel.Text = 'Natural Timbre Stimulus & SFIE Prediction';
			app.NoteLabel = uilabel(app.UIFigure);
			app.NoteLabel.Text = 'Note:';
			app.BSLabel = uilabel(app.UIFigure);
			app.BSLabel.Text = 'BS';
			app.BELabel = uilabel(app.UIFigure);
			app.BELabel.Text = 'BE';
			app.FreqLinekHzLabel = uilabel(app.UIFigure);
			app.FreqLinekHzLabel.Text = {'Freq. Line '; '(kHz)'};
			app.FreqLinekHzSlider = uislider(app.UIFigure);
			app.FreqLinekHzSlider.Limits = [1 100];
			app.FreqLinekHzSlider.MajorTicks = [1 12 32 48 64 84 100];
			app.FreqLinekHzSlider.MajorTickLabels = {'0.125', '0.2', '0.5', '1', '2', '5', '10'};
			app.FreqLinekHzSlider.ValueChangingFcn = createCallbackFcn(app, @FreqLinekHzSliderValueChanging, true);
			app.FreqLinekHzSlider.Value = 1;
			app.CFLabel = uilabel(app.UIFigure);
			app.CFLabel.Text = 'CF = ';
			app.SingleCFResponsesLabel = uilabel(app.UIFigure);
			app.SingleCFResponsesLabel.Text = 'Single CF Responses';
			app.ModelPopulationResponsesLabel = uilabel(app.UIFigure);
			app.ModelPopulationResponsesLabel.Text = 'Model Population Responses';
			app.ModelListBox_2Label = uilabel(app.UIFigure);
			app.ModelListBox_2Label.Text = 'Model:';
			app.ModelListBox_2 = uilistbox(app.UIFigure);
			app.ModelListBox_2.Items = {'Cat SFIE', 'Human SFIE', 'Cat Lat Inh'};
			app.ModelListBox_2.ValueChangedFcn = createCallbackFcn(app, @ModelListBox_2ValueChanged, true);
			app.ModelListBox_2.Value = 'Cat SFIE';
			app.CFLabel_2 = uilabel(app.UIFigure);
			app.CFLabel_2.Text = 'CF = ';
			app.DatasetsListBoxLabel = uilabel(app.UIFigure);
			app.DatasetsListBoxLabel.Text = 'Datasets';
			app.DatasetsListBox = uilistbox(app.UIFigure);
			app.DatasetsListBox.ClickedFcn = createCallbackFcn(app, @DatasetsListBoxClicked, true);
			app.SortDatasetsbyMTFTypeListBoxLabel = uilabel(app.UIFigure);
			app.SortDatasetsbyMTFTypeListBoxLabel.Text = 'Sort Datasets by MTF Type';
			app.SortDatasetsbyMTFTypeListBox = uilistbox(app.UIFigure);
			app.SortDatasetsbyMTFTypeListBox.Items = {'BE', 'BS', 'Hybrid', 'Flat'};
			app.SortDatasetsbyMTFTypeListBox.ValueChangedFcn = createCallbackFcn(app, @SortDatasetsbyMTFTypeListBoxValueChanged, true);
			app.SortDatasetsbyMTFTypeListBox.Value = 'BE';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = NT_Visualizer

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end